classdef Axial_Sectioning_Framework_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImageStackSETI15Panel           matlab.ui.container.Panel
        PercentTilingThresholdEditField  matlab.ui.control.NumericEditField
        PercentTilingThresholdEditFieldLabel  matlab.ui.control.Label
        PercentTilingOverlapEditField   matlab.ui.control.NumericEditField
        PercentTilingOverlapEditFieldLabel  matlab.ui.control.Label
        ImageStackSETI20Panel           matlab.ui.container.Panel
        PositionFileLabel               matlab.ui.control.Label
        SelectPositionFileButton        matlab.ui.control.Button
        MicroscopeConfigurationFileListBox  matlab.ui.control.ListBox
        MicroscopeConfigurationFileListBoxLabel  matlab.ui.control.Label
        ProcessDataButton               matlab.ui.control.Button
        ProcessingFolderLabel           matlab.ui.control.Label
        SelectFoldertoProcessButton     matlab.ui.control.Button
        OutputImageBitDepthDropDown     matlab.ui.control.DropDown
        OutputImageBitDepthDropDownLabel  matlab.ui.control.Label
        FlatFieldCorrectionLabel        matlab.ui.control.Label
        FlatFieldCorrectionCheckBox     matlab.ui.control.CheckBox
        SaveIntermediaryImagesCheckBox  matlab.ui.control.CheckBox
        SteeringCodeRawDataCheckBox     matlab.ui.control.CheckBox
        ReconstructionModeListBox       matlab.ui.control.ListBox
        ReconstructionModeListBoxLabel  matlab.ui.control.Label
        RejectionProfilePanel           matlab.ui.container.Panel
        NumberofSimulatedPointsforTheoryComparisonEditField  matlab.ui.control.NumericEditField
        NumberofSimulatedPointsforTheoryComparisonLabel  matlab.ui.control.Label
        ImageFileSaveTypeButtonGroup    matlab.ui.container.ButtonGroup
        tifButton                       matlab.ui.control.RadioButton
        SETIAxialSectioningReconstructionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        SETI_version = '1.5';   % Which Version of SETI is Used.
        rec_mode = 'Image Stack';   % Reconstruction Mode
        fpath = 0;  % File Path
        flat_field_path = 0;    % Path to Flat Field Correction Image
        position_file_path = 0; % Path to Position File
    end
    
    methods (Access = private)
        
        function panel_visibility_setter(app)
            % Enable Panels according to Reconstruction Type
            switch app.rec_mode
                
                case 'Individual Images'
                    app.RejectionProfilePanel.Visible = 'off';
                    app.RejectionProfilePanel.Enable = 'off';
                    
                    app.ImageStackSETI15Panel.Visible = 'off';
                    app.ImageStackSETI15Panel.Enable = 'off';
                    
                    app.ImageStackSETI20Panel.Visible = 'off';
                    app.ImageStackSETI20Panel.Enable = 'off';
                    
                case 'Image Stack'
                    app.RejectionProfilePanel.Visible = 'off';
                    app.RejectionProfilePanel.Enable = 'off';

                    switch app.SETI_version
                        case '1.5'
                            app.ImageStackSETI15Panel.Visible = 'on';
                            app.ImageStackSETI15Panel.Enable = 'on';
                            app.ImageStackSETI20Panel.Visible = 'off';
                            app.ImageStackSETI20Panel.Enable = 'off';

                        case '2.0'
                            app.ImageStackSETI15Panel.Visible = 'off';
                            app.ImageStackSETI15Panel.Enable = 'off';
                            app.ImageStackSETI20Panel.Visible = 'on';
                            app.ImageStackSETI20Panel.Enable = 'on';

                        otherwise
                            error(['\nUnsupported SETI Version. Update '...
                                'Axial Sectioning GUI\n']);
                    end
                    
                case 'Rejection Profile'
                    app.RejectionProfilePanel.Visible = 'on';
                    app.RejectionProfilePanel.Enable = 'on';
                    
                    switch app.SETI_version
                        case '1.5'
                            app.ImageStackSETI15Panel.Visible = 'on';
                            app.ImageStackSETI15Panel.Enable = 'on';
                            app.ImageStackSETI20Panel.Visible = 'off';
                            app.ImageStackSETI20Panel.Enable = 'off';

                        case '2.0'
                            app.ImageStackSETI15Panel.Visible = 'off';
                            app.ImageStackSETI15Panel.Enable = 'off';
                            app.ImageStackSETI20Panel.Visible = 'on';
                            app.ImageStackSETI20Panel.Enable = 'on';

                        otherwise
                            error(['\nUnsupported SETI Version. Update '...
                                'Axial Sectioning GUI\n']);
                    end                
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Populate the List Box with Possible Microscope Configurations
            hpath = pwd;
            cd('./Functions/Microscope Configurations');
            config_list = dir;
            config_list = struct2cell(config_list);
            config_list = config_list(1,3:end);
            for i = 1:numel(config_list)
                [~, config_list{i}, ~] = fileparts(config_list{i});
            end
            config_list{i+1} = 'Add New/Edit Configuration';
            app.MicroscopeConfigurationFileListBox.Items = config_list;
            cd(hpath);
            
            % Set Initial Panel Visibility
            panel_visibility_setter(app)
            
            
        end

        % Value changed function: ReconstructionModeListBox
        function ReconstructionModeListBoxValueChanged(app, event)
            
            % Enable Panels according to Reconstruction Type
            app.rec_mode = app.ReconstructionModeListBox.Value;
            panel_visibility_setter(app);
            
        end

        % Value changed function: FlatFieldCorrectionCheckBox
        function FlatFieldCorrectionCheckBoxValueChanged(app, event)
            
            temp_name = 0;
            if app.FlatFieldCorrectionCheckBox.Value
                [temp_name, temp_path, ~] = uigetfile('*.*', '..', ...
                    'Select Flat Field Reference File');
                app.flat_field_path = [temp_path, temp_name];
                app.FlatFieldCorrectionLabel.Text = app.flat_field_path;
                app.FlatFieldCorrectionLabel.Tooltip = app.flat_field_path;
            end
            
            if temp_name == 0
                app.FlatFieldCorrectionCheckBox.Value = 0;
            end
            
        end

        % Button pushed function: SelectFoldertoProcessButton
        function SelectFoldertoProcessButtonPushed(app, event)

            % Get a Folder Path
            app.fpath = uigetdir('..', 'Select Folder to Process');
            if app.fpath == 0
                uiwait(msgbox('Please Select a Folder to Process'));
            else
                app.ProcessingFolderLabel.Text = app.fpath;
                app.ProcessingFolderLabel.Tooltip = app.fpath;
            end

        end

        % Value changed function: MicroscopeConfigurationFileListBox
        function MicroscopeConfigurationFileListBoxValueChanged(app, event)

            if strcmp(app.MicroscopeConfigurationFileListBox.Value, ...
                    'Add New/Edit Configuration')
                % Get old List
                config_list_old = ...
                    app.MicroscopeConfigurationFileListBox.Items;

                % Navigation
                hpath = pwd;
                addpath('./Functions/Microscope Configurations');
                uiwait(Microscope_Configuration_File_Generator_GUI);

                % Populate the List Box with Possible Microscope
                % Configurations
                cd('./Functions/Microscope Configurations');
                config_list = dir;
                config_list = struct2cell(config_list);
                config_list = config_list(1,3:end);
                for i = 1:numel(config_list)
                    [~, config_list{i}, ~] = fileparts(config_list{i});
                end
                config_list{i+1} = 'Add New/Edit Configuration';
                app.MicroscopeConfigurationFileListBox.Items = ...
                    config_list;

                % Automatically Select the New Entry
                new_config_flag = 1;
                for i = 1:numel(config_list_old)
                    if ~strcmp(config_list_old{i}, config_list{i})
                        app.MicroscopeConfigurationFileListBox.Value ...
                            = config_list{i};
                        new_config_flag = 0;
                        break;
                    end
                end
                if new_config_flag == 1
                    app.MicroscopeConfigurationFileListBox.Value = ...
                        config_list{1};
                    set(handles.microscope_config_file, 'Value', 1);
                end
                cd(hpath);
            end

            temp = load(['./Functions/Microscope Configurations/'...
                app.MicroscopeConfigurationFileListBox.Value], ...
                '-mat', 'SETI_version');
            app.SETI_version = temp.SETI_version;
            
            % Set Panel Visibility
            panel_visibility_setter(app);
            
        end

        % Button pushed function: SelectPositionFileButton
        function SelectPositionFileButtonPushed(app, event)
            
            temp_name = 0;
            if app.SelectPositionFileButton.Value
                [temp_name, temp_path, ~] = uigetfile('*.xy*', '..', ...
                    'Select Position File');
                app.position_file_path = [temp_path, temp_name];
                app.PositionFileLabel.Text = app.position_file_path;
                app.PositionFileLabel.Tooltip = app.position_file_path;
            end
            
            if temp_name == 0
                app.SelectPositionFileButton.Value = 0;
            end
            
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
            
            if app.fpath == 0
                error('\nNo Folder Selected For Processing\n');
            end
            
            % Get Values
            steering_code_raw_data_flag = ...
                app.SteeringCodeRawDataCheckBox.Value;
            microscope_configuration_file = ...
                app.MicroscopeConfigurationFileListBox.Value;
            sim_points = ...
                app.NumberofSimulatedPointsforTheoryComparisonEditField.Value;
            save_intermediaries_flag = ...
                app.SaveIntermediaryImagesCheckBox.Value;
            overlap_percent = app.PercentTilingOverlapEditField.Value;
            threshold_percent = app.PercentTilingThresholdEditField.Value;
            
            img_save_type = '';
            if app.tifButton.Value
                img_save_type = '.tif';
            end
            
            bit_depth = 0;
            switch app.OutputImageBitDepthDropDown.Value
                case '8 Bit (256)'
                    bit_depth = 8;
                case'16 Bit (65,536)'
                    bit_depth = 16;
                case'32 Bit'
                    bit_depth = 32;
                case'64 Bit'
                    bit_depth = 64;
            end
            
            flat_field_flag = app.FlatFieldCorrectionCheckBox.Value;
            if numel(app.flat_field_path) == 2 && flat_field_flag == 1
                error('nNo Flat Field Reference Selected\n')
            end

            % Run Axial Sectioning Framework
            Axial_Sectioning_Framework_v11(steering_code_raw_data_flag, ...
                microscope_configuration_file, img_save_type, ...
                app.rec_mode, app.fpath, bit_depth, sim_points, ...
                save_intermediaries_flag, flat_field_flag, ...
                app.flat_field_path, overlap_percent, ...
                threshold_percent, app.position_file_path);

            % Close GUI
            delete(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SETIAxialSectioningReconstructionLabel = uilabel(app.UIFigure);
            app.SETIAxialSectioningReconstructionLabel.Text = 'SETI Axial Sectioning Reconstruction';
            app.ImageFileSaveTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageFileSaveTypeButtonGroup.Title = 'Image File Save Type';
            app.tifButton = uiradiobutton(app.ImageFileSaveTypeButtonGroup);
            app.tifButton.Tooltip = {'Select for all output images to be saved as .tif'};
            app.tifButton.Text = '.tif';
            app.tifButton.Value = true;
            app.RejectionProfilePanel = uipanel(app.UIFigure);
            app.RejectionProfilePanel.Title = 'Rejection Profile';
            app.NumberofSimulatedPointsforTheoryComparisonLabel = uilabel(app.RejectionProfilePanel);
            app.NumberofSimulatedPointsforTheoryComparisonLabel.Text = {'Number of Simulated Points '; 'for Theory Comparison'};
            app.NumberofSimulatedPointsforTheoryComparisonEditField = uieditfield(app.RejectionProfilePanel, 'numeric');
            app.NumberofSimulatedPointsforTheoryComparisonEditField.Tooltip = {'The number of points used for calculating the theoretical structured illumination axial sectioning rejection profile.'};
            app.NumberofSimulatedPointsforTheoryComparisonEditField.Value = 1000;
            app.ReconstructionModeListBoxLabel = uilabel(app.UIFigure);
            app.ReconstructionModeListBoxLabel.Text = 'Reconstruction Mode';
            app.ReconstructionModeListBox = uilistbox(app.UIFigure);
            app.ReconstructionModeListBox.Items = {'Individual Images', 'Image Stack', 'Rejection Profile'};
            app.ReconstructionModeListBox.ValueChangedFcn = createCallbackFcn(app, @ReconstructionModeListBoxValueChanged, true);
            app.ReconstructionModeListBox.Tooltip = {'Select how the processing code will reconstruct your images.'};
            app.ReconstructionModeListBox.Value = 'Image Stack';
            app.SteeringCodeRawDataCheckBox = uicheckbox(app.UIFigure);
            app.SteeringCodeRawDataCheckBox.Tooltip = {'Select if the data is directly output from the steering code.'};
            app.SteeringCodeRawDataCheckBox.Text = 'Steering Code Raw Data';
            app.SteeringCodeRawDataCheckBox.Value = true;
            app.SaveIntermediaryImagesCheckBox = uicheckbox(app.UIFigure);
            app.SaveIntermediaryImagesCheckBox.Tooltip = {'Select if you want to save intermediary steps from the processed images.'; 'NOTE: This will slow down your processing speeds.'};
            app.SaveIntermediaryImagesCheckBox.Text = 'Save Intermediary Images';
            app.FlatFieldCorrectionCheckBox = uicheckbox(app.UIFigure);
            app.FlatFieldCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlatFieldCorrectionCheckBoxValueChanged, true);
            app.FlatFieldCorrectionCheckBox.Tooltip = {'Select flat field correction of the data.'};
            app.FlatFieldCorrectionCheckBox.Text = 'Flat Field Correction';
            app.FlatFieldCorrectionLabel = uilabel(app.UIFigure);
            app.FlatFieldCorrectionLabel.Text = 'No File Selected';
            app.OutputImageBitDepthDropDownLabel = uilabel(app.UIFigure);
            app.OutputImageBitDepthDropDownLabel.Text = 'Output Image Bit-Depth';
            app.OutputImageBitDepthDropDown = uidropdown(app.UIFigure);
            app.OutputImageBitDepthDropDown.Items = {'8 Bit (256)', '16 Bit (65,536)', '32 Bit', '64 Bit'};
            app.OutputImageBitDepthDropDown.Tooltip = {'Select the Bit Depth output images should contain.'};
            app.OutputImageBitDepthDropDown.Value = '16 Bit (65,536)';
            app.SelectFoldertoProcessButton = uibutton(app.UIFigure, 'push');
            app.SelectFoldertoProcessButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFoldertoProcessButtonPushed, true);
            app.SelectFoldertoProcessButton.Tooltip = {'Push to select the folder containg all the data to process.'};
            app.SelectFoldertoProcessButton.Text = 'Select Folder to Process';
            app.ProcessingFolderLabel = uilabel(app.UIFigure);
            app.ProcessingFolderLabel.Text = 'No Folder Selected';
            app.ProcessDataButton = uibutton(app.UIFigure, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Tooltip = {'Push to start processing data.'};
            app.ProcessDataButton.Text = 'Process Data';
            app.MicroscopeConfigurationFileListBoxLabel = uilabel(app.UIFigure);
            app.MicroscopeConfigurationFileListBoxLabel.Text = 'Microscope Configuration File';
            app.MicroscopeConfigurationFileListBox = uilistbox(app.UIFigure);
            app.MicroscopeConfigurationFileListBox.Items = {};
            app.MicroscopeConfigurationFileListBox.ValueChangedFcn = createCallbackFcn(app, @MicroscopeConfigurationFileListBoxValueChanged, true);
            app.MicroscopeConfigurationFileListBox.Tooltip = {'Select which hardware configuration the microscope was in for imaging.'};
            app.MicroscopeConfigurationFileListBox.Value = {};
            app.ImageStackSETI20Panel = uipanel(app.UIFigure);
            app.ImageStackSETI20Panel.Enable = 'off';
            app.ImageStackSETI20Panel.Title = 'Image Stack - SETI 2.0';
            app.ImageStackSETI20Panel.Visible = 'off';
            app.SelectPositionFileButton = uibutton(app.ImageStackSETI20Panel, 'push');
            app.SelectPositionFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPositionFileButtonPushed, true);
            app.SelectPositionFileButton.Text = 'Select Position File';
            app.PositionFileLabel = uilabel(app.ImageStackSETI20Panel);
            app.PositionFileLabel.Text = 'No File Selected';
            app.ImageStackSETI15Panel = uipanel(app.UIFigure);
            app.ImageStackSETI15Panel.Title = 'Image Stack - SETI 1.5';
            app.PercentTilingOverlapEditFieldLabel = uilabel(app.ImageStackSETI15Panel);
            app.PercentTilingOverlapEditFieldLabel.Text = 'Percent Tiling Overlap';
            app.PercentTilingOverlapEditField = uieditfield(app.ImageStackSETI15Panel, 'numeric');
            app.PercentTilingOverlapEditField.Limits = [0 100];
            app.PercentTilingOverlapEditField.Tooltip = {'The estimated percentage overlap between adjacent images in X and Y.'};
            app.PercentTilingOverlapEditField.Value = 55;
            app.PercentTilingThresholdEditFieldLabel = uilabel(app.ImageStackSETI15Panel);
            app.PercentTilingThresholdEditFieldLabel.Text = 'Percent Tiling Threshold';
            app.PercentTilingThresholdEditField = uieditfield(app.ImageStackSETI15Panel, 'numeric');
            app.PercentTilingThresholdEditField.Tooltip = {'The thresholding percentage of maximum intensity to be used for registration.'};
            app.PercentTilingThresholdEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Axial_Sectioning_Framework_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end