classdef Microscope_Configuration_File_Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WavelengthumEditField          matlab.ui.control.NumericEditField
        WavelengthumEditFieldLabel     matlab.ui.control.Label
        SaveConfigurationButton        matlab.ui.control.Button
        EditExistingConfigurationButton  matlab.ui.control.Button
        SETIVersionButtonGroup         matlab.ui.container.ButtonGroup
        SETI20Button                   matlab.ui.control.RadioButton
        SETI15Button                   matlab.ui.control.RadioButton
        umpixelEditField               matlab.ui.control.NumericEditField
        umpixelEditFieldLabel          matlab.ui.control.Label
        PixelLinePairmmEditField       matlab.ui.control.NumericEditField
        PixelLinePairmmEditFieldLabel  matlab.ui.control.Label
        RefractiveIndexofMediumEditField  matlab.ui.control.NumericEditField
        RefractiveIndexofMediumEditFieldLabel  matlab.ui.control.Label
        FNumberEditField               matlab.ui.control.NumericEditField
        FNumberEditFieldLabel          matlab.ui.control.Label
        WorkingDistancemmEditField     matlab.ui.control.NumericEditField
        WorkingDistancemmEditFieldLabel  matlab.ui.control.Label
        ObjectiveMagnificationEditField  matlab.ui.control.NumericEditField
        ObjectiveMagnificationEditFieldLabel  matlab.ui.control.Label
        ObjectiveNAEditField           matlab.ui.control.NumericEditField
        ObjectiveNAEditFieldLabel      matlab.ui.control.Label
        MicroscopeConfigurationFileGeneratorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        edit_flag = 0;  % A flag for if user is editing an existing config
        save_path = './Functions/Microscope Configurations';
        existing_file_name = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EditExistingConfigurationButton
        function EditExistingConfigurationButtonPushed(app, event)

            % Get Existing Configuration File
            cpath = pwd;
            edit_file_selected_flag = 1;
            cd(app.save_path)
            [app.existing_file_name, existing_file_path] = ...
                uigetfile('*.mat');
            if app.existing_file_name == 0
                % User closed the window before selecting a file
                edit_file_selected_flag = 0;
            end
            cd(cpath);
            
            % Set Values Based on Load In
            if edit_file_selected_flag == 1
                % Set the Edit Existing Flag
                app.edit_flag = 1;

                % Load Congigutaion File
                load([existing_file_path '\' app.existing_file_name]);

                % Set Existing Values
                app.ObjectiveMagnificationEditField.Value = obj_mag;
                app.ObjectiveNAEditField.Value = obj_na;
                app.WorkingDistancemmEditField.Value = working_distance;
                app.FNumberEditField.Value = f_number;
                app.RefractiveIndexofMediumEditField.Value = ...
                    refractive_index_medium;
                app.WavelengthumEditField.Value = wavelength;
                app.PixelLinePairmmEditField.Value = pixel_lpmm_conversion;
                app.umpixelEditField.Value = um_pixel_conversion;
                
                switch SETI_version
                    case '1.5'
                        app.SETI15Button.Value = 1;
                    case '2.0'
                        app.SETI20Button.Value = 1;
                    otherwise
                        error(['\nUnsupported SETI version. Update '... 
                            'Microscope Configuration File Generator.\n']);
                end
            end
            
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonPushed(app, event)
            
            % Get Values
            obj_mag = app.ObjectiveMagnificationEditField.Value;
            obj_na = app.ObjectiveNAEditField.Value;
            working_distance = app.WorkingDistancemmEditField.Value;
            f_number = app.FNumberEditField.Value;
            refractive_index_medium = ...
                app.RefractiveIndexofMediumEditField.Value;
            wavelength = app.WavelengthumEditField.Value;
            pixel_lpmm_conversion = app.PixelLinePairmmEditField.Value;
            um_pixel_conversion = app.umpixelEditField.Value;
            
            if app.SETI15Button.Value == 1
                SETI_version = '1.5';
            elseif app.SETI20Button.Value == 1
                SETI_version = '2.0';
            end

            % Determine if Editing an Existing Configuration and 
            % Generate Save Name
            if app.edit_flag == 0
                cpath = pwd;
                cd(app.save_path)

                % Check for Valid New File Name
                while 1
                    fname = inputdlg('Configuration File Name', ...
                        'Enter a New Configuration File Name');
                    if ~isempty(fname)
                        [~, fname, ~] = fileparts(fname{1});
                        if exist([fname '.mat'], 'file') == 0
                            break;
                        else
                            uiwait(msgbox('Filename Already in Use.'));
                        end
                    else
                        uiwait(msgbox(...
                            'Please Enter a Configuration Name.'));
                    end
                end
                sname = [app.save_path '\' fname '.mat'];
                cd(cpath);
            else
                sname = [app.save_path '\' app.existing_file_name];
            end

            % Save Configuration File
            save(sname, 'obj_na', 'obj_mag', 'working_distance', ...
                'f_number', 'refractive_index_medium', 'wavelength', ...
                'pixel_lpmm_conversion', 'um_pixel_conversion', ...
                'SETI_version');

            % Close GUI
            delete(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MicroscopeConfigurationFileGeneratorLabel = uilabel(app.UIFigure);
            app.MicroscopeConfigurationFileGeneratorLabel.Text = 'Microscope Configuration File Generator';
            app.ObjectiveNAEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectiveNAEditFieldLabel.Text = 'Objective NA';
            app.ObjectiveNAEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectiveNAEditField.Value = 0.5;
            app.ObjectiveMagnificationEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectiveMagnificationEditFieldLabel.Text = 'Objective Magnification';
            app.ObjectiveMagnificationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectiveMagnificationEditField.Value = 10;
            app.WorkingDistancemmEditFieldLabel = uilabel(app.UIFigure);
            app.WorkingDistancemmEditFieldLabel.Text = 'Working Distance [mm]';
            app.WorkingDistancemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WorkingDistancemmEditField.Value = 1.2;
            app.FNumberEditFieldLabel = uilabel(app.UIFigure);
            app.FNumberEditFieldLabel.Text = 'F Number';
            app.FNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.FNumberEditField.Value = 7.08;
            app.RefractiveIndexofMediumEditFieldLabel = uilabel(app.UIFigure);
            app.RefractiveIndexofMediumEditFieldLabel.Text = 'Refractive Index of Medium';
            app.RefractiveIndexofMediumEditField = uieditfield(app.UIFigure, 'numeric');
            app.RefractiveIndexofMediumEditField.Value = 1;
            app.PixelLinePairmmEditFieldLabel = uilabel(app.UIFigure);
            app.PixelLinePairmmEditFieldLabel.Text = 'Pixel Line Pair/mm';
            app.PixelLinePairmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelLinePairmmEditField.Value = 380;
            app.umpixelEditFieldLabel = uilabel(app.UIFigure);
            app.umpixelEditFieldLabel.Text = 'um/pixel';
            app.umpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.umpixelEditField.Value = 0.266;
            app.SETIVersionButtonGroup = uibuttongroup(app.UIFigure);
            app.SETIVersionButtonGroup.Title = 'SETI Version';
            app.SETI15Button = uiradiobutton(app.SETIVersionButtonGroup);
            app.SETI15Button.Text = 'SETI 1.5';
            app.SETI15Button.Value = true;
            app.SETI20Button = uiradiobutton(app.SETIVersionButtonGroup);
            app.SETI20Button.Text = 'SETI 2.0';
            app.EditExistingConfigurationButton = uibutton(app.UIFigure, 'push');
            app.EditExistingConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @EditExistingConfigurationButtonPushed, true);
            app.EditExistingConfigurationButton.Text = 'Edit Existing Configuration';
            app.SaveConfigurationButton = uibutton(app.UIFigure, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save Configuration';
            app.WavelengthumEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthumEditFieldLabel.Text = 'Wavelength [um]';
            app.WavelengthumEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthumEditField.Value = 460;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Microscope_Configuration_File_Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end