classdef SIM_Pattern_Generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SIMPatternGeneratorLabel        matlab.ui.control.Label
        GeneratePatternsButton          matlab.ui.control.Button
        DegreesRotationEditField        matlab.ui.control.NumericEditField
        DegreesRotationEditFieldLabel   matlab.ui.control.Label
        DigitalApertureEditField        matlab.ui.control.NumericEditField
        DigitalApertureEditFieldLabel   matlab.ui.control.Label
        DisplayHeightEditField          matlab.ui.control.NumericEditField
        DisplayHeightEditFieldLabel     matlab.ui.control.Label
        DisplayWidthEditField           matlab.ui.control.NumericEditField
        DisplayWidthEditFieldLabel      matlab.ui.control.Label
        PatternPixelWidthEditField      matlab.ui.control.NumericEditField
        PatternPixelWidthEditFieldLabel  matlab.ui.control.Label
        PatternTypeButtonGroup          matlab.ui.container.ButtonGroup
        ImperfectSineWavePatternButton  matlab.ui.control.RadioButton
        ImperfectBarPatternButton       matlab.ui.control.RadioButton
        PerfectBarPatternButton         matlab.ui.control.RadioButton
        ReconstructionTypeButtonGroup   matlab.ui.container.ButtonGroup
        SubImage_5_Button               matlab.ui.control.RadioButton
        SubImage_4_Button               matlab.ui.control.RadioButton
        SubImage_3_Button               matlab.ui.control.RadioButton
        OutputFileTypeButtonGroup       matlab.ui.container.ButtonGroup
        tifButton                       matlab.ui.control.RadioButton
        bmpButton                       matlab.ui.control.RadioButton
        jpgButton                       matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneratePatternsButton
        function GeneratePatternsButtonPushed(app, event)
            % Get Input Variables
            pixels_wide = app.PatternPixelWidthEditField.Value;
            pattern_width = app.DisplayWidthEditField.Value;
            pattern_height = app.DisplayHeightEditField.Value;
            
            pattern = '';
            if app.PerfectBarPatternButton.Value
                pattern = 'Perfect Bar';
            elseif app.ImperfectBarPatternButton.Value
                pattern = 'Imperfect Bar';
            elseif app.ImperfectSineWavePatternButton.Value
                pattern = 'Imperfect Sine Wave';
            end
            
            reconstruction_type = '';
            file_num = 0;
            if app.SubImage_3_Button.Value
                reconstruction_type = '3 Sub Image';
                file_num = 3;
            elseif app.SubImage_4_Button.Value
                reconstruction_type = '4 Sub Image';
                file_num = 4;
            elseif app.SubImage_5_Button.Value
                reconstruction_type = '5 Sub Image';
                file_num = 5;
            end
            
            file_type = '';
            if app.tifButton.Value
                file_type = '.tif';
            elseif app.bmpButton.Value
                file_type = '.bmp';
            elseif app.jpgButton.Value
                file_type = '.jpg';
            end
            
            aperture_percent = app.DigitalApertureEditField.Value;
            rotation = app.DegreesRotationEditField.Value;
            
            
            % Generate Save Path and Check if Patterns Already Exist
            hpath = pwd;
            save_path = [pwd '\' num2str(pattern_width) 'x' ...
                num2str(pattern_height)];
            if exist(save_path, 'dir') == 0
                mkdir(save_path);
            end
            cd(save_path);
            save_path = [save_path '\' reconstruction_type ' ' pattern ...
                ' ' num2str(pixels_wide) ' Pixels ' ...
                num2str(aperture_percent) ' Aperture ' ...
                num2str(rotation) 'deg Rotation' ];
            pattern_exist_flag = 0;  % Pre-Existing Pattern Flag
            if exist(save_path, 'dir') == 0
                mkdir(save_path);
            else
                % Folder Exists. Need to Check for Content.
                cd(save_path);
                if numel(dir) == (2 + file_num)
                    % The two compensates for system folders
                    pattern_exist_flag = 1;     
                    % Patterns were previously generated.
                end
            end
            cd(hpath);
            
            
            % Generate Patterns
            if pattern_exist_flag == 0
                SIM_Pattern_Generator_v3_func(save_path, pixels_wide, ...
                    pattern_width, pattern_height, reconstruction_type, ...
                    pattern, file_type, aperture_percent, rotation);
            else
                uiwait( msgbox( ...
                    'Patterns Already Exist in the Patterns Folder.'));
            end
            
            
            % Close GUI
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OutputFileTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputFileTypeButtonGroup.Title = 'Output File Type';
            app.jpgButton = uiradiobutton(app.OutputFileTypeButtonGroup);
            app.jpgButton.Text = '.jpg';
            app.bmpButton = uiradiobutton(app.OutputFileTypeButtonGroup);
            app.bmpButton.Text = '.bmp';
            app.bmpButton.Value = true;
            app.tifButton = uiradiobutton(app.OutputFileTypeButtonGroup);
            app.tifButton.Text = '.tif';
            app.ReconstructionTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ReconstructionTypeButtonGroup.Title = 'Reconstruction Type';
            app.SubImage_3_Button = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.SubImage_3_Button.Text = '3 Sub Image';
            app.SubImage_3_Button.Value = true;
            app.SubImage_4_Button = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.SubImage_4_Button.Text = '4 Sub Image';
            app.SubImage_5_Button = uiradiobutton(app.ReconstructionTypeButtonGroup);
            app.SubImage_5_Button.Text = '5 Sub Image';
            app.PatternTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PatternTypeButtonGroup.Title = 'Pattern Type';
            app.PerfectBarPatternButton = uiradiobutton(app.PatternTypeButtonGroup);
            app.PerfectBarPatternButton.Text = 'Perfect Bar Pattern';
            app.PerfectBarPatternButton.Value = true;
            app.ImperfectBarPatternButton = uiradiobutton(app.PatternTypeButtonGroup);
            app.ImperfectBarPatternButton.Text = 'Imperfect Bar Pattern';
            app.ImperfectSineWavePatternButton = uiradiobutton(app.PatternTypeButtonGroup);
            app.ImperfectSineWavePatternButton.Text = 'Imperfect Sine Wave Pattern';
            app.PatternPixelWidthEditFieldLabel = uilabel(app.UIFigure);
            app.PatternPixelWidthEditFieldLabel.Text = 'Pattern Pixel Width';
            app.PatternPixelWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatternPixelWidthEditField.Limits = [0 Inf];
            app.PatternPixelWidthEditField.Value = 18;
            app.DisplayWidthEditFieldLabel = uilabel(app.UIFigure);
            app.DisplayWidthEditFieldLabel.Text = 'Display Width';
            app.DisplayWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplayWidthEditField.Limits = [1 Inf];
            app.DisplayWidthEditField.Value = 1920;
            app.DisplayHeightEditFieldLabel = uilabel(app.UIFigure);
            app.DisplayHeightEditFieldLabel.Text = 'Display Height';
            app.DisplayHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplayHeightEditField.Limits = [1 Inf];
            app.DisplayHeightEditField.Value = 1080;
            app.DigitalApertureEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalApertureEditFieldLabel.Text = '% Digital Aperture';
            app.DigitalApertureEditField = uieditfield(app.UIFigure, 'numeric');
            app.DigitalApertureEditField.Limits = [0 100];
            app.DegreesRotationEditFieldLabel = uilabel(app.UIFigure);
            app.DegreesRotationEditFieldLabel.Text = 'Degrees Rotation';
            app.DegreesRotationEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeneratePatternsButton = uibutton(app.UIFigure, 'push');
            app.GeneratePatternsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePatternsButtonPushed, true);
            app.GeneratePatternsButton.Text = 'Generate Patterns';
            app.SIMPatternGeneratorLabel = uilabel(app.UIFigure);
            app.SIMPatternGeneratorLabel.Text = 'SIM Pattern Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIM_Pattern_Generator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end